classdef SpectrumAnalyserApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        AnySignalTab                  matlab.ui.container.Tab
        SignalEquationEditFieldLabel  matlab.ui.control.Label
        SignalEquationEditField       matlab.ui.control.EditField
        Time0Label                    matlab.ui.control.Label
        Time0EditField                matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        AnalyseButton                 matlab.ui.control.Button
        ResultEditFieldLabel          matlab.ui.control.Label
        ResultEditField               matlab.ui.control.EditField
        AudioSignalTab                matlab.ui.container.Tab
        UIAxes_3                      matlab.ui.control.UIAxes
        FileNameorURLEditFieldLabel   matlab.ui.control.Label
        FileNameorURLEditField        matlab.ui.control.EditField
        UIAxes_4                      matlab.ui.control.UIAxes
        AnalyseButton_2               matlab.ui.control.Button
        PlaySoundButton               matlab.ui.control.Button
        ChooseExistingDropDownLabel   matlab.ui.control.Label
        ChooseExistingDropDown        matlab.ui.control.DropDown
        ResultEditField_2Label        matlab.ui.control.Label
        ResultEditField_2             matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalyseButton_2
        function AnalyseButton_2Pushed(app, event)
            source=app.FileNameorURLEditField.Value;
            if string(source)=='Enter FileName'
                source=app.ChooseExistingDropDown.Value
                app.FileNameorURLEditField.Value=source;
            end
            try
                    app.ResultEditField_2.Value='Computing..';
                    [x,fs] = audioread(source);
                    y=fft(x);
                    m = length(x);
                    n = pow2(nextpow2(m));
                    power = abs(y).^2/n; 
                    plot(app.UIAxes_3,x);
                    plot(app.UIAxes_4,abs(y));
                    app.ResultEditField_2.Value='Success'
            catch
                app.ResultEditField_2.Value='Cannot find audio file';
                app.FileNameorURLEditField.Value='Enter FileName'
            end
                
        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            source=app.FileNameorURLEditField.Value;
            if string(source)=='Enter FileName'
                source=app.ChooseExistingDropDown.Value;
                app.FileNameorURLEditField.Value=source;
            end
            try
                app.ResultEditField_2.Value='Computing..';
                [x,fs] = audioread(source);
                app.ResultEditField_2.Value='Playing audio';
                sound(x,fs);
    
            catch
                app.ResultEditField_2.Value='Cant find audio file';
                app.FileNameorURLEditField.Value='Enter FileName'
            end
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
          try
              app.ResultEditField.Value='computing..'
              p=app.Time0EditField.Value;
              t=0:.01:p;
              x=eval(app.SignalEquationEditField.Value);
              y=fft(x);
              plot(app.UIAxes,x);
              plot(app.UIAxes_2,abs(y));
              app.ResultEditField.Value='Success!!'
          catch
              app.ResultEditField.Value='Error!!'
          end
        end

        % Value changed function: ChooseExistingDropDown
        function ChooseExistingDropDownValueChanged(app, event)
            value = app.ChooseExistingDropDown.Value;
            app.FileNameorURLEditField.Value=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnySignalTab = uitab(app.TabGroup);
            app.AnySignalTab.Title = 'Any Signal';
            app.SignalEquationEditFieldLabel = uilabel(app.AnySignalTab);
            app.SignalEquationEditFieldLabel.Text = 'Signal Equation';
            app.SignalEquationEditField = uieditfield(app.AnySignalTab, 'text');
            app.Time0Label = uilabel(app.AnySignalTab);
            app.Time0Label.Text = 'Time-  0  --';
            app.Time0EditField = uieditfield(app.AnySignalTab, 'numeric');
            app.UIAxes = uiaxes(app.AnySignalTab);
            title(app.UIAxes, 'Signal in time domain')
            xlabel(app.UIAxes, {'Time'; ''})
            ylabel(app.UIAxes, {'Amplitude'; ''})
            app.UIAxes_2 = uiaxes(app.AnySignalTab);
            title(app.UIAxes_2, {'Signal in frequency domain'; ''})
            xlabel(app.UIAxes_2, 'Frequency')
            ylabel(app.UIAxes_2, {'Amplitude'; ''})
            app.AnalyseButton = uibutton(app.AnySignalTab, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.ResultEditFieldLabel = uilabel(app.AnySignalTab);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.AnySignalTab, 'text');
            app.AudioSignalTab = uitab(app.TabGroup);
            app.AudioSignalTab.Title = 'Audio Signal';
            app.UIAxes_3 = uiaxes(app.AudioSignalTab);
            title(app.UIAxes_3, {'Signal in time domain'; ''})
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Amplitude')
            app.FileNameorURLEditFieldLabel = uilabel(app.AudioSignalTab);
            app.FileNameorURLEditFieldLabel.Text = 'FileName or URL';
            app.FileNameorURLEditField = uieditfield(app.AudioSignalTab, 'text');
            app.FileNameorURLEditField.Value = 'Enter FileName';
            app.UIAxes_4 = uiaxes(app.AudioSignalTab);
            title(app.UIAxes_4, 'Signal in frequency domain')
            xlabel(app.UIAxes_4, 'Frequency')
            ylabel(app.UIAxes_4, {'Amplitude'; ''})
            app.AnalyseButton_2 = uibutton(app.AudioSignalTab, 'push');
            app.AnalyseButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButton_2Pushed, true);
            app.AnalyseButton_2.Text = 'Analyse';
            app.PlaySoundButton = uibutton(app.AudioSignalTab, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.ChooseExistingDropDownLabel = uilabel(app.AudioSignalTab);
            app.ChooseExistingDropDownLabel.Text = 'Choose Existing';
            app.ChooseExistingDropDown = uidropdown(app.AudioSignalTab);
            app.ChooseExistingDropDown.Items = {'WoodPeker.mp3', 'sparrow.mp3', 'RedParot.mp3', 'osprey.mp3', 'frogs.mp3', 'bluewhale.au'};
            app.ChooseExistingDropDown.ItemsData = {'WoodPeker.mp3', 'sparrow.mp3', 'RedParot.mp3', 'osprey.mp3', 'frogs.mp3', 'bluewhale.au'};
            app.ChooseExistingDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseExistingDropDownValueChanged, true);
            app.ChooseExistingDropDown.Value = 'WoodPeker.mp3';
            app.ResultEditField_2Label = uilabel(app.AudioSignalTab);
            app.ResultEditField_2Label.Text = 'Result';
            app.ResultEditField_2 = uieditfield(app.AudioSignalTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectrumAnalyserApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end